classdef Lab03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LogButton         matlab.ui.control.Button
        LaplacianButton   matlab.ui.control.Button
        SobelButton       matlab.ui.control.Button
        PrewittButton     matlab.ui.control.Button
        GaussianButton    matlab.ui.control.Button
        AverageButton     matlab.ui.control.Button
        NoiseGaussButton  matlab.ui.control.Button
        NoiseRPButton     matlab.ui.control.Button
        OpenButton        matlab.ui.control.Button
        CloseButton       matlab.ui.control.Button
        UIAxes_6          matlab.ui.control.UIAxes
        UIAxes_5          matlab.ui.control.UIAxes
        UIAxes_4          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global image1
            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            image1=imread([path1,file1]);
            imshow(image1, 'Parent', app.UIAxes);
        end

        % Button pushed function: NoiseRPButton
        function NoiseRPButtonPushed(app, event)
            global image1
            global image2
            image2 = imnoise(image1,'salt & pepper');
            imshow(image2, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: NoiseGaussButton
        function NoiseGaussButtonPushed(app, event)
            global image1
            global image3
            image3 = imnoise(image1,'gaussian');
            imshow(image3, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image4
            global image5
            global image6
            filter = fspecial('average');
            image4 = imfilter(image1, filter);
            imshow(image4, 'Parent', app.UIAxes_4);
            image5 = imfilter(image2, filter);
            imshow(image5, 'Parent', app.UIAxes_5);
            image6 = imfilter(image3, filter);
            imshow(image6, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image7
            global image8
            global image9
            filter = fspecial('gaussian');
            image8 = imfilter(image1, filter);
            imshow(image8, 'Parent', app.UIAxes_4);
            image8 = imfilter(image2, filter);
            imshow(image8, 'Parent', app.UIAxes_5);
            image9 = imfilter(image3, filter);
            imshow(image9, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image10
            global image11
            global image12
            filter = fspecial('prewitt');
            image10 = imfilter(image1, filter);
            imshow(image10, 'Parent', app.UIAxes_4);
            image11 = imfilter(image2, filter);
            imshow(image11, 'Parent', app.UIAxes_5);
            image12 = imfilter(image3, filter);
            imshow(image12, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image13
            global image14
            global image15
            filter = fspecial('sobel');
            image13 = imfilter(image1, filter);
            imshow(image13, 'Parent', app.UIAxes_4);
            image14 = imfilter(image2, filter);
            imshow(image14, 'Parent', app.UIAxes_5);
            image15 = imfilter(image3, filter);
            imshow(image15, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image16
            global image17
            global image18
            filter = fspecial('laplacian');
            image16 = imfilter(image1, filter);
            imshow(image16, 'Parent', app.UIAxes_4);
            image17 = imfilter(image2, filter);
            imshow(image17, 'Parent', app.UIAxes_5);
            image18 = imfilter(image3, filter);
            imshow(image18, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            global image1
            global image2
            global image3
            global image19
            global image20
            global image21
            filter = fspecial('log');
            image19 = imfilter(image1, filter);
            imshow(image19, 'Parent', app.UIAxes_4);
            image20 = imfilter(image2, filter);
            imshow(image20, 'Parent', app.UIAxes_5);
            image21 = imfilter(image3, filter);
            imshow(image21, 'Parent', app.UIAxes_6);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'R&P')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Gauss')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Original')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Filtered R&P')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Filtered Gauss')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.NoiseRPButton = uibutton(app.UIFigure, 'push');
            app.NoiseRPButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseRPButtonPushed, true);
            app.NoiseRPButton.Text = 'Noise R&P';
            app.NoiseGaussButton = uibutton(app.UIFigure, 'push');
            app.NoiseGaussButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseGaussButtonPushed, true);
            app.NoiseGaussButton.Text = 'Noise Gauss';
            app.AverageButton = uibutton(app.UIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplacianButton = uibutton(app.UIFigure, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end