classdef Lab04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AngleEditField          matlab.ui.control.NumericEditField
        AngleEditFieldLabel     matlab.ui.control.Label
        ChannelEditField        matlab.ui.control.NumericEditField
        ChannelEditFieldLabel   matlab.ui.control.Label
        ColumnEditField         matlab.ui.control.NumericEditField
        ColumnEditFieldLabel    matlab.ui.control.Label
        RowEditField            matlab.ui.control.NumericEditField
        RowEditFieldLabel       matlab.ui.control.Label
        RotationButton          matlab.ui.control.Button
        ScaleButton             matlab.ui.control.Button
        CutoutquarterButton     matlab.ui.control.Button
        FliphorizontallyButton  matlab.ui.control.Button
        FlipverticallyButton    matlab.ui.control.Button
        NormalizationButton     matlab.ui.control.Button
        DarkerButton            matlab.ui.control.Button
        BrighterButton          matlab.ui.control.Button
        RestoreButton           matlab.ui.control.Button
        GrayButton              matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage
            global image1
            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            originalImage=imread([path1,file1]);
            % originalImage = imread("lenna.png");
            image1 = originalImage;
            imshow(originalImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            global originalImage
            global originalGrayImage
            global image2
            originalGrayImage = rgb2gray(originalImage);
            image2 = originalGrayImage;
            imshow(originalGrayImage, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            global originalImage
            global originalGrayImage
            global image1
            global image2
            image1 = originalImage;
            image2 = originalGrayImage;
            imshow(originalImage, 'Parent', app.UIAxes_3);
            imshow(originalGrayImage, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: BrighterButton
        function BrighterButtonPushed(app, event)
            global image1
            global image2
            image1 = image1 + 10;
            image2 = image2 + 10;
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: DarkerButton
        function DarkerButtonPushed(app, event)
            global image1
            global image2
            image1 = image1 - 10;
            image2 = image2 - 10;
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: NormalizationButton
        function NormalizationButtonPushed(app, event)
            global image1
            global image2

            % Separate the RGB channels of image1
            redChannel = image1(:, :, 1);
            greenChannel = image1(:, :, 2);
            blueChannel = image1(:, :, 3);
        
            % Normalize each channel separately
            redChannel = imadjust(redChannel);
            greenChannel = imadjust(greenChannel);
            blueChannel = imadjust(blueChannel);
        
            % Combine the normalized channels to create the normalized RGB image
            image1 = cat(3, redChannel, greenChannel, blueChannel);

            image2 = imadjust(image2);
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: FlipverticallyButton
        function FlipverticallyButtonPushed(app, event)
            global image1
            global image2
            image1 = fliplr(image1);
            image2 = fliplr(image2);
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: FliphorizontallyButton
        function FliphorizontallyButtonPushed(app, event)
            global image1
            global image2
            image1 = flipud(image1);
            image2 = flipud(image2);
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: CutoutquarterButton
        function CutoutquarterButtonPushed(app, event)
            global image1
            global image2
            [X, Y, Z] = size(image1);
            newX = floor(X / 2);
            newY = floor(Y / 2);
            image1 = image1(1:newX, 1:newY, :);
            image2 = image2(1:newX, 1:newY, :);
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            global image1
            global image2
            rowSize = app.RowEditField.Value;
            columnSize = app.ColumnEditField.Value;
            channelSize = app.ChannelEditField.Value;
            image1 = imresize3(image1, [rowSize, columnSize, channelSize]);
            image2 = imresize(image2, [rowSize, columnSize]);
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            global image1
            global image2
            angle = app.AngleEditField.Value;
            image1 = imrotate(image1, angle, 'bilinear','crop');
            image2 = imrotate(image2, angle, 'bilinear','crop');
            imshow(image1, 'Parent', app.UIAxes_3);
            imshow(image2, 'Parent', app.UIAxes_4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Gray')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Processed Original')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Processed Gray')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GrayButton = uibutton(app.UIFigure, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.RestoreButton = uibutton(app.UIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.BrighterButton = uibutton(app.UIFigure, 'push');
            app.BrighterButton.ButtonPushedFcn = createCallbackFcn(app, @BrighterButtonPushed, true);
            app.BrighterButton.Text = 'Brighter';
            app.DarkerButton = uibutton(app.UIFigure, 'push');
            app.DarkerButton.ButtonPushedFcn = createCallbackFcn(app, @DarkerButtonPushed, true);
            app.DarkerButton.Text = 'Darker';
            app.NormalizationButton = uibutton(app.UIFigure, 'push');
            app.NormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizationButtonPushed, true);
            app.NormalizationButton.Text = 'Normalization';
            app.FlipverticallyButton = uibutton(app.UIFigure, 'push');
            app.FlipverticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipverticallyButtonPushed, true);
            app.FlipverticallyButton.Text = 'Flip vertically';
            app.FliphorizontallyButton = uibutton(app.UIFigure, 'push');
            app.FliphorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FliphorizontallyButtonPushed, true);
            app.FliphorizontallyButton.Text = 'Flip horizontally';
            app.CutoutquarterButton = uibutton(app.UIFigure, 'push');
            app.CutoutquarterButton.ButtonPushedFcn = createCallbackFcn(app, @CutoutquarterButtonPushed, true);
            app.CutoutquarterButton.Text = 'Cut-out quarter';
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.RotationButton = uibutton(app.UIFigure, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowEditField.Value = 50;
            app.ColumnEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnEditField.Value = 50;
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditField.Value = 3;
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Limits = [-360 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end