classdef Lab05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GrayImageButton          matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
        OpenButton               matlab.ui.control.Button
        DilationButton           matlab.ui.control.Button
        ErosionButton            matlab.ui.control.Button
        BinarizationButton       matlab.ui.control.Button
        MEDFilterButton          matlab.ui.control.Button
        MINFilterButton          matlab.ui.control.Button
        MAXFilterButton          matlab.ui.control.Button
        OpenImageButton          matlab.ui.control.Button
        CloseprogramButton       matlab.ui.control.Button
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseprogramButton
        function CloseprogramButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            global originalImage

            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            originalImage=imread([path1,file1]);
            % originalImage = imread("lenna.png");

            imshow(originalImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: MAXFilterButton
        function MAXFilterButtonPushed(app, event)
            global originalImage
            global grayImage
            
            % Three seperate filtering for three channels
            redChannel = ordfilt2(originalImage(:, :, 1), 9, ones(3,3));
            greenChannel = ordfilt2(originalImage(:, :, 2), 9, ones(3,3));
            blueChannel = ordfilt2(originalImage(:, :, 3), 9, ones(3,3));
            filteredColoredImage = cat(3, redChannel, greenChannel, blueChannel);
            
            filteredGrayImage = ordfilt2(grayImage, 9, ones(3,3));

            imshow(filteredColoredImage, 'Parent', app.UIAxes_3);
            imshow(filteredGrayImage, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: MINFilterButton
        function MINFilterButtonPushed(app, event)
            global originalImage
            global grayImage
            
            % Three seperate filtering for three channels
            redChannel = ordfilt2(originalImage(:, :, 1), 1, ones(3,3));
            greenChannel = ordfilt2(originalImage(:, :, 2), 1, ones(3,3));
            blueChannel = ordfilt2(originalImage(:, :, 3), 1, ones(3,3));
            filteredColoredImage = cat(3, redChannel, greenChannel, blueChannel);
            
            filteredGrayImage = ordfilt2(grayImage, 1, ones(3,3));

            imshow(filteredColoredImage, 'Parent', app.UIAxes_3);
            imshow(filteredGrayImage, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: MEDFilterButton
        function MEDFilterButtonPushed(app, event)
            global originalImage
            global grayImage
            
            % Three seperate filtering for three channels
            redChannel = ordfilt2(originalImage(:, :, 1), 5, ones(3,3));
            greenChannel = ordfilt2(originalImage(:, :, 2), 5, ones(3,3));
            blueChannel = ordfilt2(originalImage(:, :, 3), 5, ones(3,3));
            filteredColoredImage = cat(3, redChannel, greenChannel, blueChannel);
            
            filteredGrayImage = ordfilt2(grayImage, 5, ones(3,3));

            imshow(filteredColoredImage, 'Parent', app.UIAxes_3);
            imshow(filteredGrayImage, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: BinarizationButton
        function BinarizationButtonPushed(app, event)
            global grayImage
            global binaryImage

            threshold = app.ThresholdEditField.Value;
            binaryImage = imbinarize(grayImage, threshold / 255);
            imshow(binaryImage, 'Parent', app.UIAxes_5);
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            global binaryImage

            se = strel('rectangle', [3 3]);
            binaryImage = imerode(binaryImage, se);
            imshow(binaryImage, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
            global binaryImage

            se = strel('rectangle', [3 3]);
            binaryImage = imdilate(binaryImage, se);
            imshow(binaryImage, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global binaryImage

            se = strel('rectangle', [3 3]);
            binaryImage = imopen(binaryImage, se);
            imshow(binaryImage, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            global binaryImage

            se = strel('rectangle', [3 3]);
            binaryImage = imclose(binaryImage, se);
            imshow(binaryImage, 'Parent', app.UIAxes_6);
        end

        % Button pushed function: GrayImageButton
        function GrayImageButtonPushed(app, event)
            global originalImage
            global grayImage

            grayImage = rgb2gray(originalImage);
            imshow(grayImage, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '4')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '5')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '6')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.CloseprogramButton = uibutton(app.UIFigure, 'push');
            app.CloseprogramButton.ButtonPushedFcn = createCallbackFcn(app, @CloseprogramButtonPushed, true);
            app.CloseprogramButton.Text = 'Close program';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.MAXFilterButton = uibutton(app.UIFigure, 'push');
            app.MAXFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MAXFilterButtonPushed, true);
            app.MAXFilterButton.Text = 'MAX Filter';
            app.MINFilterButton = uibutton(app.UIFigure, 'push');
            app.MINFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MINFilterButtonPushed, true);
            app.MINFilterButton.Text = 'MIN Filter';
            app.MEDFilterButton = uibutton(app.UIFigure, 'push');
            app.MEDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MEDFilterButtonPushed, true);
            app.MEDFilterButton.Text = 'MED Filter';
            app.BinarizationButton = uibutton(app.UIFigure, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizationButtonPushed, true);
            app.BinarizationButton.Text = 'Binarization';
            app.ErosionButton = uibutton(app.UIFigure, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.DilationButton = uibutton(app.UIFigure, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 255];
            app.ThresholdEditField.Value = 100;
            app.GrayImageButton = uibutton(app.UIFigure, 'push');
            app.GrayImageButton.ButtonPushedFcn = createCallbackFcn(app, @GrayImageButtonPushed, true);
            app.GrayImageButton.Text = 'Gray Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end