classdef Lab06 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        MSERButton      matlab.ui.control.Button
        SURFButton      matlab.ui.control.Button
        MinEigenButton  matlab.ui.control.Button
        HarrisButton    matlab.ui.control.Button
        FASTButton      matlab.ui.control.Button
        GrayButton      matlab.ui.control.Button
        OpenButton      matlab.ui.control.Button
        CloseButton     matlab.ui.control.Button
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage

            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            originalImage=imread([path1,file1]);
            % originalImage = imread("lenna.png");

            imshow(originalImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            global originalImage
            global grayImage

            grayImage = rgb2gray(originalImage);
            imshow(grayImage, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: FASTButton
        function FASTButtonPushed(app, event)
            global originalImage
            global grayImage

            coordinates = detectFASTFeatures(grayImage).Location;
            coloredImage = originalImage;
            for i=1:size(coordinates,1)
                x = round(coordinates(i, 1));
                y = round(coordinates(i, 2));
                coloredImage(y,x,:) = [255,255,255];
            end
            imshow(coloredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: HarrisButton
        function HarrisButtonPushed(app, event)
            global originalImage
            global grayImage

            coordinates = detectHarrisFeatures(grayImage).Location;
            coloredImage = originalImage;
            for i=1:size(coordinates,1)
                x = round(coordinates(i, 1));
                y = round(coordinates(i, 2));
                coloredImage(y,x,:) = [255,255,255];
            end
            imshow(coloredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: MinEigenButton
        function MinEigenButtonPushed(app, event)
            global originalImage
            global grayImage

            coordinates = detectMinEigenFeatures(grayImage).Location;
            coloredImage = originalImage;
            for i=1:size(coordinates,1)
                x = round(coordinates(i, 1));
                y = round(coordinates(i, 2));
                coloredImage(y,x,:) = [255,255,255];
            end
            imshow(coloredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: SURFButton
        function SURFButtonPushed(app, event)
            global originalImage
            global grayImage

            coordinates = detectSURFFeatures(grayImage).Location;
            coloredImage = originalImage;
            for i=1:size(coordinates,1)
                x = round(coordinates(i, 1));
                y = round(coordinates(i, 2));
                coloredImage(y,x,:) = [255,255,255];
            end
            imshow(coloredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: MSERButton
        function MSERButtonPushed(app, event)
            global originalImage
            global grayImage

            coordinates = detectMSERFeatures(grayImage).Location;
            coloredImage = originalImage;
            for i=1:size(coordinates,1)
                x = round(coordinates(i, 1));
                y = round(coordinates(i, 2));
                coloredImage(y,x,:) = [255,255,255];
            end
            imshow(coloredImage, 'Parent', app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GrayButton = uibutton(app.UIFigure, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.FASTButton = uibutton(app.UIFigure, 'push');
            app.FASTButton.ButtonPushedFcn = createCallbackFcn(app, @FASTButtonPushed, true);
            app.FASTButton.Text = 'FAST';
            app.HarrisButton = uibutton(app.UIFigure, 'push');
            app.HarrisButton.ButtonPushedFcn = createCallbackFcn(app, @HarrisButtonPushed, true);
            app.HarrisButton.Text = 'Harris';
            app.MinEigenButton = uibutton(app.UIFigure, 'push');
            app.MinEigenButton.ButtonPushedFcn = createCallbackFcn(app, @MinEigenButtonPushed, true);
            app.MinEigenButton.Text = 'MinEigen';
            app.SURFButton = uibutton(app.UIFigure, 'push');
            app.SURFButton.ButtonPushedFcn = createCallbackFcn(app, @SURFButtonPushed, true);
            app.SURFButton.Text = 'SURF';
            app.MSERButton = uibutton(app.UIFigure, 'push');
            app.MSERButton.ButtonPushedFcn = createCallbackFcn(app, @MSERButtonPushed, true);
            app.MSERButton.Text = 'MSER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab06

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end