classdef Lab07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CompareMSERFButton  matlab.ui.control.Button
        CompareSURFButton   matlab.ui.control.Button
        CompareFREAKButton  matlab.ui.control.Button
        CompareSIFTButton   matlab.ui.control.Button
        OpenSmallButton     matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage

            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            originalImage=imread([path1,file1]);
            % originalImage = imread("lenna.png");

            imshow(originalImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: OpenSmallButton
        function OpenSmallButtonPushed(app, event)
            global originalImage
            global smallImage

            [height, width, ~] = size(originalImage);
            smallImage = originalImage(1:height/2, 1:width, :);

            imshow(smallImage, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: CompareSIFTButton
        function CompareSIFTButtonPushed(app, event)
            global originalImage
            global smallImage

            grayImage1 = rgb2gray(originalImage);
            grayImage2 = rgb2gray(smallImage);

            points1 = detectSIFTFeatures(grayImage1).Location;
            points2 = detectSIFTFeatures(grayImage2).Location;
            [features1, validPoints1] = extractFeatures(grayImage1, points1);
            [features2, validPoints2] = extractFeatures(grayImage2, points2);

            indexPairs = matchFeatures(features1,features2);

            matchedPoints1 = validPoints1(indexPairs(:, 1), :);
            matchedPoints2 = validPoints2(indexPairs(:, 2), :);

            markedImage1 = insertMarker(originalImage, matchedPoints1, "x-mark");
            markedImage2 = insertMarker(smallImage, matchedPoints2, "x-mark");

            imshow(markedImage1, 'Parent', app.UIAxes);
            imshow(markedImage2, 'Parent', app.UIAxes_2);

            showMatchedFeatures(originalImage, smallImage, matchedPoints1, matchedPoints2, 'montage', 'Parent', app.UIAxes_3);
        end

        % Button pushed function: CompareFREAKButton
        function CompareFREAKButtonPushed(app, event)
            global originalImage
            global smallImage

            grayImage1 = rgb2gray(originalImage);
            grayImage2 = rgb2gray(smallImage);

            points1 = detectHarrisFeatures(grayImage1).Location;
            points2 = detectHarrisFeatures(grayImage2).Location;
            [features1, validPoints1] = extractFeatures(grayImage1, points1, Method="FREAK");
            [features2, validPoints2] = extractFeatures(grayImage2, points2, Method="FREAK");

            indexPairs = matchFeatures(features1,features2);

            matchedPoints1 = validPoints1(indexPairs(:, 1), :);
            matchedPoints2 = validPoints2(indexPairs(:, 2), :);

            markedImage1 = insertMarker(originalImage, matchedPoints1, "x-mark");
            markedImage2 = insertMarker(smallImage, matchedPoints2, "x-mark");

            imshow(markedImage1, 'Parent', app.UIAxes);
            imshow(markedImage2, 'Parent', app.UIAxes_2);

            showMatchedFeatures(originalImage, smallImage, matchedPoints1, matchedPoints2, 'montage', 'Parent', app.UIAxes_3);
        end

        % Button pushed function: CompareSURFButton
        function CompareSURFButtonPushed(app, event)
            global originalImage
            global smallImage

            grayImage1 = rgb2gray(originalImage);
            grayImage2 = rgb2gray(smallImage);

            points1 = detectSURFFeatures(grayImage1).Location;
            points2 = detectSURFFeatures(grayImage2).Location;
            [features1, validPoints1] = extractFeatures(grayImage1, points1);
            [features2, validPoints2] = extractFeatures(grayImage2, points2);

            indexPairs = matchFeatures(features1,features2);

            matchedPoints1 = validPoints1(indexPairs(:, 1), :);
            matchedPoints2 = validPoints2(indexPairs(:, 2), :);

            markedImage1 = insertMarker(originalImage, matchedPoints1, "x-mark");
            markedImage2 = insertMarker(smallImage, matchedPoints2, "x-mark");

            imshow(markedImage1, 'Parent', app.UIAxes);
            imshow(markedImage2, 'Parent', app.UIAxes_2);

            showMatchedFeatures(originalImage, smallImage, matchedPoints1, matchedPoints2, 'montage', 'Parent', app.UIAxes_3);
        end

        % Button pushed function: CompareMSERFButton
        function CompareMSERFButtonPushed(app, event)
            global originalImage
            global smallImage

            grayImage1 = rgb2gray(originalImage);
            grayImage2 = rgb2gray(smallImage);

            points1 = detectMSERFeatures(grayImage1).Location;
            points2 = detectMSERFeatures(grayImage2).Location;
            [features1, validPoints1] = extractFeatures(grayImage1, points1);
            [features2, validPoints2] = extractFeatures(grayImage2, points2);

            indexPairs = matchFeatures(features1,features2);

            matchedPoints1 = validPoints1(indexPairs(:, 1), :);
            matchedPoints2 = validPoints2(indexPairs(:, 2), :);

            markedImage1 = insertMarker(originalImage, matchedPoints1, "x-mark");
            markedImage2 = insertMarker(smallImage, matchedPoints2, "x-mark");

            imshow(markedImage1, 'Parent', app.UIAxes);
            imshow(markedImage2, 'Parent', app.UIAxes_2);

            showMatchedFeatures(originalImage, smallImage, matchedPoints1, matchedPoints2, 'montage', 'Parent', app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.OpenSmallButton = uibutton(app.UIFigure, 'push');
            app.OpenSmallButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSmallButtonPushed, true);
            app.OpenSmallButton.Text = 'Open Small';
            app.CompareSIFTButton = uibutton(app.UIFigure, 'push');
            app.CompareSIFTButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSIFTButtonPushed, true);
            app.CompareSIFTButton.Text = 'Compare SIFT';
            app.CompareFREAKButton = uibutton(app.UIFigure, 'push');
            app.CompareFREAKButton.ButtonPushedFcn = createCallbackFcn(app, @CompareFREAKButtonPushed, true);
            app.CompareFREAKButton.Text = 'Compare FREAK';
            app.CompareSURFButton = uibutton(app.UIFigure, 'push');
            app.CompareSURFButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSURFButtonPushed, true);
            app.CompareSURFButton.Text = 'Compare SURF';
            app.CompareMSERFButton = uibutton(app.UIFigure, 'push');
            app.CompareMSERFButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMSERFButtonPushed, true);
            app.CompareMSERFButton.Text = 'Compare MSERF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab07

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end