classdef Lab08 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CloseButton         matlab.ui.control.Button
        SegmentationButton  matlab.ui.control.Button
        Sampling3Button     matlab.ui.control.Button
        Sampling2Button     matlab.ui.control.Button
        Sampling1Button     matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        UIAxes_5            matlab.ui.control.UIAxes
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage
            global originalImageCopy

            [file1, path1]=uigetfile('*.jpg;*.png;*.bmp', 'Load the image');
            originalImage=imread([path1,file1]);
            % originalImage = imread("flowers.jpg");
            originalImageCopy = originalImage;
            imshow(originalImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: Sampling1Button
        function Sampling1ButtonPushed(app, event)
            global originalImage
            global template1
            P = impixel(originalImage);

            minValues = inf * ones(1, size(P, 2));
            maxValues = -inf * ones(1, size(P, 2));
            
            for col = 1:size(P, 2)
                column_values = P(:, col);
                minValues(col) = min(column_values);
                maxValues(col) = max(column_values);
            end
            
            template1 = ((originalImage(:,:,1) >= minValues(1) - 10 & originalImage(:,:,1) <= maxValues(1) + 10) & ...
                (originalImage(:,:,2) >= minValues(2) - 10 & originalImage(:,:,2) <= maxValues(2) + 10) & ...
                (originalImage(:,:,3) >= minValues(3) - 10 & originalImage(:,:,3) <= maxValues(3) + 10)) * 255;

            imshow(template1, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: Sampling2Button
        function Sampling2ButtonPushed(app, event)
            global originalImage
            global template2
            P = impixel(originalImage);

            minValues = inf * ones(1, size(P, 2));
            maxValues = -inf * ones(1, size(P, 2));
            
            for col = 1:size(P, 2)
                column_values = P(:, col);
                minValues(col) = min(column_values);
                maxValues(col) = max(column_values);
            end
            
            template2 = ((originalImage(:,:,1) >= minValues(1) - 10 & originalImage(:,:,1) <= maxValues(1) + 10) & ...
                (originalImage(:,:,2) >= minValues(2) - 10 & originalImage(:,:,2) <= maxValues(2) + 10) & ...
                (originalImage(:,:,3) >= minValues(3) - 10 & originalImage(:,:,3) <= maxValues(3) + 10)) * 255;

            imshow(template2, 'Parent', app.UIAxes_4);
        end

        % Button pushed function: Sampling3Button
        function Sampling3ButtonPushed(app, event)
            global originalImage
            global template3
            P = impixel(originalImage);

            minValues = inf * ones(1, size(P, 2));
            maxValues = -inf * ones(1, size(P, 2));
            
            for col = 1:size(P, 2)
                column_values = P(:, col);
                minValues(col) = min(column_values);
                maxValues(col) = max(column_values);
            end
            
            template3 = ((originalImage(:,:,1) >= minValues(1) - 10 & originalImage(:,:,1) <= maxValues(1) + 10) & ...
                (originalImage(:,:,2) >= minValues(2) - 10 & originalImage(:,:,2) <= maxValues(2) + 10) & ...
                (originalImage(:,:,3) >= minValues(3) - 10 & originalImage(:,:,3) <= maxValues(3)) + 10) * 255;

            imshow(template3, 'Parent', app.UIAxes_5);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            global originalImageCopy
            global template1
            global template2
            global template3

            originalImageCopy(:,:,1) = 255;
            originalImageCopy(:,:,2) = 255;
            originalImageCopy(:,:,3) = 0;

            [rows, cols, ~] = size(originalImageCopy);
            
            for row = 1:rows
                for col = 1:cols
                    if template1(row, col) == 255
                        originalImageCopy(row, col, :) = [255, 0, 0];  % Make pixel red
                    end
            
                    if template2(row, col) == 255
                        originalImageCopy(row, col, :) = [0, 255, 0];  % Make pixel green
                    end
            
                    if template3(row, col) == 255
                        originalImageCopy(row, col, :) = [0, 0, 255];  % Make pixel blue
                    end
                end
            end
            
            % Display the modified image
            imshow(originalImageCopy, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Sampling1Button = uibutton(app.UIFigure, 'push');
            app.Sampling1Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling1ButtonPushed, true);
            app.Sampling1Button.Text = 'Sampling 1';
            app.Sampling2Button = uibutton(app.UIFigure, 'push');
            app.Sampling2Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling2ButtonPushed, true);
            app.Sampling2Button.Text = 'Sampling 2';
            app.Sampling3Button = uibutton(app.UIFigure, 'push');
            app.Sampling3Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling3ButtonPushed, true);
            app.Sampling3Button.Text = 'Sampling 3';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab08

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end